classdef app_MOLTO_bien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InitialOrbitPanel               matlab.ui.container.Panel
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        eEditFieldLabel                 matlab.ui.control.Label
        eEditField                      matlab.ui.control.NumericEditField
        iEditFieldLabel                 matlab.ui.control.Label
        iEditField                      matlab.ui.control.NumericEditField
        oEditFieldLabel                 matlab.ui.control.Label
        oEditField                      matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        wEditField                      matlab.ui.control.NumericEditField
        thetaEditFieldLabel             matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.NumericEditField
        SemimajoraxisLabel              matlab.ui.control.Label
        RAANLabel                       matlab.ui.control.Label
        EccentricityLabel               matlab.ui.control.Label
        InclinationLabel                matlab.ui.control.Label
        ArgumentofperiapsesLabel        matlab.ui.control.Label
        TrueAnomalyLabel                matlab.ui.control.Label
        FreeCheckBox                    matlab.ui.control.CheckBox
        FreeCheckBox_2                  matlab.ui.control.CheckBox
        FreeCheckBox_3                  matlab.ui.control.CheckBox
        FreeCheckBox_4                  matlab.ui.control.CheckBox
        kmLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        TargetOrbitPanel                matlab.ui.container.Panel
        aEditField_2Label               matlab.ui.control.Label
        aEditField_2                    matlab.ui.control.NumericEditField
        eEditField_2Label               matlab.ui.control.Label
        eEditField_2                    matlab.ui.control.NumericEditField
        iEditField_2Label               matlab.ui.control.Label
        iEditField_2                    matlab.ui.control.NumericEditField
        oEditField_2Label               matlab.ui.control.Label
        oEditField_2                    matlab.ui.control.NumericEditField
        wEditField_2Label               matlab.ui.control.Label
        wEditField_2                    matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        thetaEditField_2                matlab.ui.control.NumericEditField
        SemimajoraxisLabel_2            matlab.ui.control.Label
        RAANLabel_2                     matlab.ui.control.Label
        EccentricityLabel_2             matlab.ui.control.Label
        InclinationLabel_2              matlab.ui.control.Label
        ArgumentofperiapsesLabel_2      matlab.ui.control.Label
        TrueAnomalyLabel_2              matlab.ui.control.Label
        FreeCheckBox_5                  matlab.ui.control.CheckBox
        FreeCheckBox_6                  matlab.ui.control.CheckBox
        FreeCheckBox_7                  matlab.ui.control.CheckBox
        FreeCheckBox_8                  matlab.ui.control.CheckBox
        kmLabel_2                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SingleTransferPropertiesPanel   matlab.ui.container.Panel
        WeightParametersPanel           matlab.ui.container.Panel
        W_aEditFieldLabel               matlab.ui.control.Label
        W_aEditField                    matlab.ui.control.EditField
        W_fEditFieldLabel               matlab.ui.control.Label
        W_fEditField                    matlab.ui.control.EditField
        W_gEditFieldLabel               matlab.ui.control.Label
        W_gEditField                    matlab.ui.control.EditField
        W_hEditFieldLabel               matlab.ui.control.Label
        W_hEditField                    matlab.ui.control.EditField
        W_kEditFieldLabel               matlab.ui.control.Label
        W_kEditField                    matlab.ui.control.EditField
        EffectivityParametersPanel      matlab.ui.container.Panel
        etaAbsoluteEditFieldLabel       matlab.ui.control.Label
        etaAbsoluteEditField            matlab.ui.control.EditField
        etaRelativeEditFieldLabel       matlab.ui.control.Label
        etaRelativeEditField            matlab.ui.control.EditField
        ScaleFunctionParametersPanel    matlab.ui.container.Panel
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.EditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.EditField
        ProblemtypeDropDownLabel        matlab.ui.control.Label
        ProblemtypeDropDown             matlab.ui.control.DropDown
        SpacecraftPropertiesPanel       matlab.ui.container.Panel
        m_0EditFieldLabel               matlab.ui.control.Label
        m_0EditField                    matlab.ui.control.EditField
        T_maxEditFieldLabel             matlab.ui.control.Label
        T_maxEditField                  matlab.ui.control.EditField
        IspEditFieldLabel               matlab.ui.control.Label
        IspEditField                    matlab.ui.control.EditField
        InitialMassLabel                matlab.ui.control.Label
        MaximumThrustLabel              matlab.ui.control.Label
        SpecificImpulseLabel            matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        PerturbationsConstraintsPanel   matlab.ui.container.Panel
        EclipseCheckBox                 matlab.ui.control.CheckBox
        MinimumRpCheckBox               matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.NumericEditField
        J2CheckBox                      matlab.ui.control.CheckBox
        kmLabel_3                       matlab.ui.control.Label
        SimulationOptionsPanel          matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        MaximumTransferTimeEditFieldLabel  matlab.ui.control.Label
        MaximumTransferTimeEditField    matlab.ui.control.NumericEditField
        FreeCheckBox_9                  matlab.ui.control.CheckBox
        StartTransferDateDatePickerLabel  matlab.ui.control.Label
        StartTransferDateDatePicker     matlab.ui.control.DatePicker
        DatePicker                      matlab.ui.control.DatePicker
        OptimizatorOptionsPanel         matlab.ui.container.Panel
        MaxPopSizeEditFieldLabel        matlab.ui.control.Label
        MaxPopSizeEditField             matlab.ui.control.NumericEditField
        MaxGenerationEditFieldLabel     matlab.ui.control.Label
        MaxGenerationEditField          matlab.ui.control.NumericEditField
        CrossoverFractionEditFieldLabel  matlab.ui.control.Label
        CrossoverFractionEditField      matlab.ui.control.NumericEditField
        MutationFractionEditFieldLabel  matlab.ui.control.Label
        MutationFractionEditField       matlab.ui.control.NumericEditField
        ParallelComputationCheckBox     matlab.ui.control.CheckBox
        CentralBodyPropertiesPanel      matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        GravParamEditFieldLabel         matlab.ui.control.Label
        GravParamEditField              matlab.ui.control.NumericEditField
        J2EditFieldLabel                matlab.ui.control.Label
        J2EditField                     matlab.ui.control.NumericEditField
        BodyRadiusEditFieldLabel        matlab.ui.control.Label
        BodyRadiusEditField             matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        degLabel_2                      matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        degLabel_7                      matlab.ui.control.Label
        degLabel_8                      matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        yearsLabel                      matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        OptimizatorTab                  matlab.ui.container.Tab
        UIAxes_pareto                   matlab.ui.control.UIAxes
        GenerationListBoxLabel          matlab.ui.control.Label
        GenerationListBox               matlab.ui.control.ListBox
        PopulationListBoxLabel          matlab.ui.control.Label
        PopulationListBox               matlab.ui.control.ListBox
        TransferOrbitCostPanel_2        matlab.ui.container.Panel
        kmsLabel_2                      matlab.ui.control.Label
        daysLabel_2                     matlab.ui.control.Label
        DeltaVEditField_2Label          matlab.ui.control.Label
        DeltaVEditField_2               matlab.ui.control.EditField
        TransferTimeEditField_2Label    matlab.ui.control.Label
        TransferTimeEditField_2         matlab.ui.control.EditField
        PlottransferButton              matlab.ui.control.Button
        COETab                          matlab.ui.container.Tab
        UIAxes_a                        matlab.ui.control.UIAxes
        UIAxes_e                        matlab.ui.control.UIAxes
        UIAxes_i                        matlab.ui.control.UIAxes
        UIAxes_o                        matlab.ui.control.UIAxes
        UIAxes_w                        matlab.ui.control.UIAxes
        UIAxes_nu                       matlab.ui.control.UIAxes
        ParamsTab                       matlab.ui.container.Tab
        UIAxes_alpha                    matlab.ui.control.UIAxes
        UIAxes_beta                     matlab.ui.control.UIAxes
        UIAxes_thrust                   matlab.ui.control.UIAxes
        UIAxes_q                        matlab.ui.control.UIAxes
        UIAxes_dv                       matlab.ui.control.UIAxes
        UIAxes_mass                     matlab.ui.control.UIAxes
        OrbitTab                        matlab.ui.container.Tab
        InitialOrbitCheckBox            matlab.ui.control.CheckBox
        FinalOrbitCheckBox              matlab.ui.control.CheckBox
        TransferOrbitCheckBox           matlab.ui.control.CheckBox
        UIAxes_orbit                    matlab.ui.control.UIAxes
        TransferOrbitCostPanel          matlab.ui.container.Panel
        kmsLabel                        matlab.ui.control.Label
        daysLabel                       matlab.ui.control.Label
        DeltaVEditFieldLabel            matlab.ui.control.Label
        DeltaVEditField                 matlab.ui.control.EditField
        TransferTimeEditFieldLabel      matlab.ui.control.Label
        TransferTimeEditField           matlab.ui.control.EditField
    end

    
    properties (Access = private) 
        body_mass; % Mass of the central body
        
        plot_a;  % Plot of semimajor axis
        plot_e;  % Plot Eccentricity 
        plot_i;  % Plot inclination 
        plot_o;  % Plot RAAN 
        plot_w;  % Plot Argument of periapses 
        plot_nu; % Plot True Anomaly 
        
        plot_alpha; 
        plot_beta; 
        plot_mass; 
        plot_dv; 
        plot_thrust; 
        plot_q; 
        plot_orbit;
        plot_orbit_2; 
        plot_orbit_t;
        plot_orbit_t_thrust;
        plot_orbit_t_coast;
        plot_orbit_t_eclipse;
        h; % earth 
        X; % earth
        Y; % earth
        Z; % earth 
        res_opt; 
        pareto_chosen; 
        trajectories; 
        cost_opt; 
        pos_00;  % Initial orbit 
        pos_ff;  % Target orbit  
        pos_transfer; % Transfer orbit 
    end
    
    properties (Access = public)
        result; 
        currentGen; 
        listPop;
        output;% Description
        pareto; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clc
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%            % Reset graphs 
%            clear app.plot_orbit_t
%            clc
            app.body_mass = 5.972e24; 
            
            % Storing  Initial Orbit Parameters
            initial.a = app.aEditField.Value*10^3;
            initial.e = app.eEditField.Value;
            initial.i = deg2rad(app.iEditField.Value);
            initial.o = deg2rad(app.oEditField.Value);
            initial.w = deg2rad(app.wEditField.Value);
            initial.nu = deg2rad(app.thetaEditField.Value);
            
            %Storing Target Orbit Parameters
            target.a = app.aEditField_2.Value*10^3;
            target.e = app.eEditField_2.Value;
            target.i = deg2rad(app.iEditField_2.Value);
            target.o = deg2rad(app.oEditField_2.Value);
            target.w = deg2rad(app.wEditField_2.Value);
            target.nu = deg2rad(app.thetaEditField_2.Value);
            
            %Storing  Spacecraft Properties Parameters
            inputs.initMass = str2num(app.m_0EditField.Value);
            inputs.maxThrust = str2num(app.T_maxEditField.Value); 
            inputs.Isp = str2num(app.IspEditField.Value);
            
            % Storing Perturbations Panel 
            inputs.min_rp_enabled = app.MinimumRpCheckBox.Value;
            inputs.min_rp_threshold = 10^3*app.EditField.Value;
            inputs.eclipse_enabled = app.EclipseCheckBox.Value;
            inputs.J2_enabled = app.J2CheckBox.Value;


            %Storing Simulation Parameters 
            inputs.max_transfer_time = 3.153600000e+07*app.MaximumTransferTimeEditField.Value;
            inputs.transferStartJulianDate = app.StartTransferDateDatePicker.Value;
            inputs.maxStartJD = app.DatePicker.Value;
            
            %Storing Single Transfer Parameters 
            inputs.problem_type = app.ProblemtypeDropDown.Value;
            % Weights 
            %inputs.weights = zeros(1,5);
            inputs.weights(1) = str2num(app.W_aEditField.Value);
            inputs.weights(2) = str2num(app.W_fEditField.Value);
            inputs.weights(3) = str2num(app.W_gEditField.Value);
            inputs.weights(4) = str2num(app.W_hEditField.Value);
            inputs.weights(5) = str2num(app.W_kEditField.Value);
            
            % Etas 
            inputs.eta_cut_abs = str2num(app.etaAbsoluteEditField.Value);
            inputs.eta_cut_rel = str2num(app.etaRelativeEditField.Value);
            
            % Scaling Func Param 
            inputs.scale_func_m = str2num(app.mEditField.Value);
            inputs.scale_func_n = str2num(app.nEditField.Value);
            inputs.scale_func_r = str2num(app.rEditField.Value);
            
            % Central Body Parameters 
            inputs.J2 = app.J2EditField.Value;
            inputs.mu = app.GravParamEditField.Value;
            inputs.Re = app.BodyRadiusEditField.Value; 
            inputs.body_mass = app.body_mass;
            
            % Free Variables 
            inputs.freeVars(1) = app.FreeCheckBox.Value;
            inputs.freeVars(2) = app.FreeCheckBox_2.Value;
            inputs.freeVars(3) = app.FreeCheckBox_3.Value;
            inputs.freeVars(4) = app.FreeCheckBox_4.Value;
            inputs.freeVars(5) = app.FreeCheckBox_5.Value;
            inputs.freeVars(6) = app.FreeCheckBox_6.Value;
            inputs.freeVars(7) = app.FreeCheckBox_7.Value;
            inputs.freeVars(8) = app.FreeCheckBox_8.Value; 
            inputs.freeVars(9) = app.FreeCheckBox_9.Value;
            
            % Genetic Algorithm options 
            nsga.max_pop = app.MaxPopSizeEditField.Value;
            nsga.max_gen = app.MaxGenerationEditField.Value;
            nsga.crossover_rate = app.CrossoverFractionEditField.Value;
            nsga.mutation_rate = app.MutationFractionEditField.Value;
            nsga.parallel = app.ParallelComputationCheckBox.Value;
            
            
            % First output 
            cellArrayText{1} = sprintf('\n%s\n%s', 'Inputs received','--------------------------');
            app.TextArea.Value = cellArrayText;
            if isequal(  app.ProblemtypeDropDown.Value,"Single Transfer")  
            cellArrayText{2} = sprintf('%s\n%s', 'Single Transfer Problem','--------------------------');
            else
            cellArrayText{2} = sprintf('%s\n%s\n%s', 'Optimization problem','--------------------------','Please go to Optimization Tab to check state of calculation');    
            end
            app.TextArea.Value = cellArrayText;
            drawnow
            %hola = pruebas(initial,target,inputs,nsga);
            [cost,trajectory,results] =  main2(inputs,initial,target,nsga,app); 
            
            % Plotting options 
            if isequal( app.ProblemtypeDropDown.Value,"Single Transfer") 
               size = length(trajectory); 
%                    is = 1; % Index eclipse 
%                    coast = 1; % Index coast 
%                    tc = 1; % Index thrust
               for count = 1:size 
                   
                   
                   a(count) = trajectory(count).Coe.a; 
                   e(count) = trajectory(count).Coe.e;
                   i(count) = rad2deg(trajectory(count).Coe.i);
                   o(count) = rad2deg(trajectory(count).Coe.o);
                   w(count) = rad2deg(trajectory(count).Coe.w);
                   nu(count) = rad2deg(trajectory(count).Coe.nu);
                   
                   alpha(count) = rad2deg(trajectory(count).Alpha);
                   beta(count) = rad2deg(trajectory(count).Beta); 
                   mass(count) = trajectory(count).Mass;
                   Qvalue(count) = trajectory(count).Qvalue; 
                   Thrust(count) = trajectory(count).Thrust;
                   deltaV(count) = trajectory(count).DeltaV/1000; 
                   time(count) = trajectory(count).Ti; 
                   pos_t(count,:) = trajectory(count).pos; 
                   
                   
                   % Assign color to the plot depending on the arc 
                   if trajectory(count).Thrust == 0 && trajectory(count).InShadow == 0 
                       color(count) = "b";
                       %pos_coast(coast,:) = trajectory(count).pos;
                       times_coast(count) = time(count);
                       times_is(count) = 0.002;
                       times_tc(count) = 0.002;
                       %coast = coast + 1;
                   elseif trajectory(count).InShadow == 1 && trajectory(count).Thrust == 0 
                       color(count) = "r";
                       %pos_is(is,:) = trajectory(count).pos;
                       times_coast(count) = 0.002;
                       times_is(count) = time(count);
                       times_tc(count) = 0.002;
                       %is = is + 1;
                   else
                       color(count) = "c";
                       %pos_tc(tc,:) = trajectory(count).pos;
                       times_coast(count) = 0.002;
                       times_is(count) = 0.002;
                       times_tc(count) = time(count);
                       %tc = tc + 1;
                   end               
               end 
               
               % Print solution 
               cellArrayText{3} = sprintf('%s%4.3f %s\n%s', 'Total Delta V = ' ,deltaV(end),'km/s','--------------------------');
               cellArrayText{4} = sprintf('%s%f %s\n%s', 'Total Fuel Mass = ' ,mass(1)-mass(end),'kg','--------------------------');
               cellArrayText{5} = sprintf('%s%f %s\n%s', 'Total Time of Flight = ' ,time(end)/24/3600,'days','--------------------------');
               app.TextArea.Value = cellArrayText;
               
               % Calculate intervals of time when each phase happen
               
%                count1 = 1; 
%                inter_tc = 1;  
%                inter_coast = 1;
%                inter_is = 1;
%                while count1 < size 
%                    % Thrust intervals 
% 
%                   if color(count1) == 'c'
%                       interval_tc(inter_tc,1) = count1; 
%                       interval_tc(inter_tc,2) = 0; 
%                    while color(count1 + 1) == 'c'
%                        interval_tc(inter_tc,2) = count1 + 1; 
%                        count1 = count1 + 1;
%                    end
%                    inter_tc = inter_tc + 1; 
%                    % Coast intervals 
%                    
%                   elseif color(count1) == 'b'
%                    interval_coast(inter_coast,1) = count1;
%                    interval_coast(inter_coast,2) = 0;
%                    while color(count1 + 1) == 'b'
%                        interval_coast(inter_coast,2) = count1 + 1; 
%                        count1 = count1 + 1;
%                    end
%                    inter_coast = inter_coast + 1;     
%                    % Eclipse 
%                    
%                   else 
%                    interval_is(inter_is,1) = count1;
%                    interval_is(inter_is,2) = 0; 
%                    while color(count1 + 1) == 'r'
%                        interval_is(inter_is,2) = count1 + 1; 
%                        count1 = count1 + 1;
%                    end
%                    inter_is = inter_is + 1; 
%                   end
%                   count1 = count1 + 1;
%                end
%                       
                vec = linspace(time(1),time(end),3.8*size); 
%                
%                % Assign colors 
%                origin = 1; 
%                ending = 0; 
%                index_is = [];
%                for ii = 1:length(interval_is) 
%                    index_1 = find(vec>=time(interval_is(ii,1)) & vec<=time(interval_is(ii,2)) );
%                    ending = ending + length(index_1); 
%                    index_is(origin:ending) = index_1;
%                    origin = origin + ending; 
%                end 


               pos_t_smooth(:,1) = spline(time',pos_t(:,1),vec');
               pos_t_smooth(:,2) = spline(time',pos_t(:,2),vec');
               pos_t_smooth(:,3) = spline(time',pos_t(:,3),vec'); 
               
               app.pos_transfer = pos_t_smooth;
%                pos_coast_smooth(:,1) = spline(time',pos_coast(:,1),vec');
%                pos_coast_smooth(:,2) = spline(time',pos_coast(:,2),vec');
%                pos_coast_smooth(:,3) = spline(time',pos_coast(:,3),vec');
%                pos_tc_smooth(:,1) = spline(time',pos_tc(:,1),vec');
%                pos_tc_smooth(:,2) = spline(time',pos_tc(:,2),vec');
%                pos_tc_smooth(:,3) = spline(time',pos_tc(:,3),vec');
%                pos_is_smooth(:,1) = spline(time',pos_is(:,1),vec');
%                pos_is_smooth(:,2) = spline(time',pos_is(:,2),vec');
%                pos_is_smooth(:,3) = spline(time',pos_is(:,3),vec');
               
               
               app.plot_a = plot(app.UIAxes_a,time/3600/24,a,'k'); 
               app.plot_e = plot(app.UIAxes_e,time/3600/24,e,'k'); 
               app.plot_i = plot(app.UIAxes_i,time/3600/24,i,'k'); 
               app.plot_o = plot(app.UIAxes_o,time/3600/24,o,'k'); 
               app.plot_w = plot(app.UIAxes_w,time/3600/24,w,'k'); 
               app.plot_nu = plot(app.UIAxes_nu,time/3600/24,nu,'k'); 
               ylabel(app.UIAxes_nu,'$\Theta$','interpreter','latex');
               
               app.plot_alpha = plot(app.UIAxes_alpha,time/3600/24,alpha,'k'); 
               ylabel(app.UIAxes_alpha,'$\alpha [deg]$','interpreter','latex');
               app.plot_beta = plot(app.UIAxes_beta,time/3600/24,beta,'k'); 
               ylabel(app.UIAxes_beta,'$\beta [deg]$','interpreter','latex');
               app.plot_mass = plot(app.UIAxes_mass,time/3600/24,mass,'k'); 
               app.plot_dv = plot(app.UIAxes_dv,time/3600/24,deltaV,'k');
               ylabel(app.UIAxes_dv,'$\Delta V$ $\frac{km}{s}$','interpreter','latex');
               app.plot_q = plot(app.UIAxes_q,time/3600/24,Qvalue,'k'); 
               app.plot_thrust = stairs(app.UIAxes_thrust,time/3600/24,Thrust,'k'); 
               
               % Plot of the orbit
               
               % Initial and final orbits 
               TA = linspace(0,2*pi,64); 
               for idx = 1:length(TA) 
                 
                   orbit_0(idx).a = initial.a; 
                   orbit_0(idx).e = initial.e;
                   orbit_0(idx).i = initial.i;
                   orbit_0(idx).o = initial.o;
                   orbit_0(idx).w = initial.w;
                   orbit_0(idx).nu = TA(idx);
                   pos_0(idx,:) = COE_getPosition(orbit_0(idx)); 


                   orbit_f(idx).a = target.a; 
                   orbit_f(idx).e = target.e;
                   orbit_f(idx).i = target.i;
                   orbit_f(idx).o = target.o;
                   orbit_f(idx).w = target.w;
                   orbit_f(idx).nu = TA(idx);
                   pos_f(idx,:) = COE_getPosition(orbit_f(idx));
               end 
               app.pos_00 = pos_0; 
               app.pos_ff = pos_f; 
               
               % Plot Earth
               image_earth = 'http://upload.wikimedia.org/wikipedia/commons/thumb/c/cd/Land_ocean_ice_2048.jpg/1024px-Land_ocean_ice_2048.jpg';
               cdata = imread(image_earth);
               alpha = 1;

               %dim = size(pos_f,2);
               [app.X,app.Y,app.Z] = ellipsoid(app.UIAxes_orbit,0,0,0,6378,6378,6378);
               app.h = surfl(app.UIAxes_orbit,app.X, app.Y, -app.Z);
               hold(app.UIAxes_orbit,'on')
               set (app.h, 'FaceColor', [0.7 0.7 0.7]);
               set(app.h, 'FaceColor', 'texturemap', 'CData', cdata, 'FaceAlpha', alpha, 'EdgeColor', 'none');
               axis(app.UIAxes_orbit,'equal')
               hold(app.UIAxes_orbit,'on')
               
               % Plot initial orbit 
                               
               app.plot_orbit = plot3(app.UIAxes_orbit,pos_0(:,1)/1000,pos_0(:,2)/1000,pos_0(:,3)/1000,'y','LineWidth',2);
               hold(app.UIAxes_orbit,'on')
               
               % Plot final orbit 
               app.plot_orbit_2 = plot3(app.UIAxes_orbit,pos_f(:,1)/1000,pos_f(:,2)/1000,pos_f(:,3)/1000,'y','LineWidth',2);

               hold(app.UIAxes_orbit,'on') 
               
%                for p = 1:size 
%                  app.plot_orbit_t(p) = plot3(app.UIAxes_orbit,pos_t_smooth(p,1)/1000,pos_t_smooth(p,2)/1000,pos_t_smooth(p,3)/1000,'Color',color(p));
%                  hold(app.UIAxes_orbit,'on') 
%                end
               app.plot_orbit_t = plot3(app.UIAxes_orbit,pos_t_smooth(:,1)/1000,pos_t_smooth(:,2)/1000,pos_t_smooth(:,3)/1000,'Color','w');

%                app.plot_orbit_t_thrust = plot3(app.UIAxes_orbit,pos_tc_smooth(:,1)/1000,pos_tc_smooth(:,2)/1000,pos_tc_smooth(:,3)/1000,'c');
%                hold(app.UIAxes_orbit,'on') 
%                app.plot_orbit_t_coast = plot3(app.UIAxes_orbit,pos_coast_smooth(:,1)/1000,pos_coast_smooth(:,2)/1000,pos_coast_smooth(:,3)/1000,'b');
%                hold(app.UIAxes_orbit,'on') 
%                app.plot_orbit_t_eclipse = plot3(app.UIAxes_orbit,pos_is_smooth(:,1)/1000,pos_is_smooth(:,2)/1000,pos_is_smooth(:,3)/1000,'r');
%                

               % Showing cost values 
               app.DeltaVEditField.Value = num2str(cost(2)); 
               app.TransferTimeEditField.Value = num2str(cost(1));
            else 
                cellArrayText{3} = sprintf('%s\n%s\n','--------------------------','Choose the desired transfer to be plotted');
                app.TextArea.Value = cellArrayText;
                
                % Activating optimization tab 
                app.GenerationListBox.Enable = 'on'; 
                app.PopulationListBox.Enable = 'on';
               app.cost_opt = cost; 
               app.res_opt = results; 
               app.trajectories = trajectory;
                
            end
        end

        % Value changed function: ProblemtypeDropDown
        function ProblemtypeDropDownValueChanged(app, event)
            value = app.ProblemtypeDropDown.Value; 
            
            if isequal( value,"Single Transfer") 
                app.SingleTransferPropertiesPanel.Visible = 'on';
                app.W_aEditField.Enable = 'on';
                app.W_fEditField.Enable = 'on';
                app.W_gEditField.Enable = 'on';
                app.W_hEditField.Enable = 'on';
                app.W_kEditField.Enable = 'on';
                app.etaAbsoluteEditField.Enable = 'on';
                app.etaRelativeEditField.Enable = 'on';
                app.mEditField.Enable = 'on';
                app.nEditField.Enable = 'on';
                app.rEditField.Enable = 'on';
            else
                %app.SingleTransferPropertiesPanel.Visible = 'off'; 
                app.W_aEditField.Enable = 'off';
                app.W_fEditField.Enable = 'off';
                app.W_gEditField.Enable = 'off';
                app.W_hEditField.Enable = 'off';
                app.W_kEditField.Enable = 'off';
                app.etaAbsoluteEditField.Enable = 'off';
                app.etaRelativeEditField.Enable = 'off';
                app.mEditField.Enable = 'off';
                app.nEditField.Enable = 'off';
                app.rEditField.Enable = 'off';
            end
        end

        % Value changed function: FreeCheckBox_9
        function FreeCheckBox_9ValueChanged(app, event)
            value = app.FreeCheckBox_9.Value;
            if value == 1 
                app.DatePicker.Enable = 'on'; 
            else
                app.DatePicker.Enable = 'off';
            end
        end

        % Value changed function: MinimumRpCheckBox
        function MinimumRpCheckBoxValueChanged(app, event)
            value = app.MinimumRpCheckBox.Value;
            if value == 1 
                app.EditField.Enable = 'on'; 
                app.kmLabel_3.Enable = 'on';
            else
                app.EditField.Enable = 'off'; 
                app.kmLabel_3.Enable = 'off';
            end
        end

        % Value changed function: FreeCheckBox
        function FreeCheckBoxValueChanged(app, event)
            value = app.FreeCheckBox.Value; 
            if value == 1
            app.eEditField.Enable = 'off';
            else 
            app.eEditField.Enable = 'on';
            end
        end

        % Value changed function: FreeCheckBox_2
        function FreeCheckBox_2ValueChanged(app, event)
            value = app.FreeCheckBox_2.Value;
            if value == 1
            app.iEditField.Enable = 'off';
            else 
            app.iEditField.Enable = 'on';
            end            
        end

        % Value changed function: FreeCheckBox_3
        function FreeCheckBox_3ValueChanged(app, event)
            value = app.FreeCheckBox_3.Value;
            if value == 1
            app.oEditField.Enable = 'off';
            else 
            app.oEditField.Enable = 'on';
            end            
        end

        % Value changed function: FreeCheckBox_4
        function FreeCheckBox_4ValueChanged(app, event)
            value = app.FreeCheckBox_4.Value;
            if value == 1
            app.wEditField.Enable = 'off';
            else 
            app.wEditField.Enable = 'on';
            end            
        end

        % Value changed function: FreeCheckBox_5
        function FreeCheckBox_5ValueChanged(app, event)
            value = app.FreeCheckBox_5.Value;
            if value == 1
            app.aEditField_2.Enable = 'off';
            else 
            app.aEditField_2.Enable = 'on';
            end               
        end

        % Value changed function: FreeCheckBox_6
        function FreeCheckBox_6ValueChanged(app, event)
            value = app.FreeCheckBox_6.Value;
            if value == 1
            app.iEditField_2.Enable = 'off';
            else 
            app.iEditField_2.Enable = 'on';
            end 
        end

        % Value changed function: FreeCheckBox_7
        function FreeCheckBox_7ValueChanged(app, event)
            value = app.FreeCheckBox_7.Value;
            if value == 1
            app.oEditField_2.Enable = 'off';
            else 
            app.oEditField_2.Enable = 'on';
            end 
        end

        % Value changed function: FreeCheckBox_8
        function FreeCheckBox_8ValueChanged(app, event)
            value = app.FreeCheckBox_8.Value;
            if value == 1
            app.wEditField_2.Enable = 'off';
            else 
            app.wEditField_2.Enable = 'on';
            end 
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Earth" 
                app.GravParamEditField.Value = 398600440000000; 
                app.J2EditField.Value = 0.001082626; 
                app.BodyRadiusEditField.Value = 6378140; 
                app.body_mass = 5.972e24; %kg 
            elseif value == "Moon"
                app.GravParamEditField.Value = 4902.799e9; 
                app.J2EditField.Value = 0.0002027; 
                app.BodyRadiusEditField.Value = 1738.0e3;
                app.body_mass = 7.35e22; %kg 
            elseif value == "Mars"
                app.GravParamEditField.Value = 4.305e13; 
                app.J2EditField.Value = 0.001964; 
                app.BodyRadiusEditField.Value = 3397.2e3;  
                app.body_mass = 6.39e23; %kg
            end
        end

        % Value changed function: MaxGenerationEditField
        function MaxGenerationEditFieldValueChanged(app, event)
            value = app.MaxGenerationEditField.Value;
            nb_items = 1:value; 
            for i = 1:value 
                items{i} = sprintf('%d', nb_items(i));
            end
            app.GenerationListBox.Items = items;
        end

        % Value changed function: GenerationListBox, PopulationListBox
        function GenerationPopulationListBoxValueChanged(app, event)
            cla(app.UIAxes_pareto);
            value1 = app.GenerationListBox.Value;
            value2 = app.PopulationListBox.Value;
            % Change title to Pareto Plot
            strTitle = sprintf('Generation %s / %d', value1, app.MaxGenerationEditField.Value);
            title(app.UIAxes_pareto,strTitle, 'interpreter', 'latex') 
            
            % Choose Pareto data 
            pop     = app.res_opt.pops(str2num(value1), :);
            obj     = vertcat(pop.obj); 
            app.pareto = plot(app.UIAxes_pareto,obj(:,1), obj(:,2), 'ob'); 
            
            % Choose Objective Data and remark it in the plot 
            hold(app.UIAxes_pareto,'on')
            if isequal(value1,app.GenerationListBox.Items{end})
            app.pareto_chosen = plot(app.UIAxes_pareto,obj(str2num(value2),1), obj(str2num(value2),2), 'or');
                app.PopulationListBox.Enable = 'on';
                app.PlottransferButton.Enable = 'on';  
            app.DeltaVEditField_2.Value = num2str(obj(str2num(value2),2));
            app.TransferTimeEditField_2.Value = num2str(obj(str2num(value2),1)); 
            else 
                app.PlottransferButton.Enable = 'off'; 
                app.PopulationListBox.Enable = 'off';
            app.DeltaVEditField_2.Value = num2str(0);
            app.TransferTimeEditField_2.Value = num2str(0); 
            end
            
        end

        % Value changed function: MaxPopSizeEditField
        function MaxPopSizeEditFieldValueChanged(app, event)
            value = app.MaxPopSizeEditField.Value;
                        nb_items = 1:value; 
            for i = 1:value 
                items{i} = sprintf('%d', nb_items(i));
            end
            app.PopulationListBox.Items = items;
        end

        % Button pushed function: PlottransferButton
        function PlottransferButtonPushed(app, event)
            trajectory = app.trajectories{str2num(app.PopulationListBox.Value)}; 
            cost = app.cost_opt;
            % Obtain plots
            size = length(trajectory); 
%                    is = 1; % Index eclipse 
%                    coast = 1; % Index coast 
%                    tc = 1; % Index thrust
               for count = 1:size 
                   
                   
                   a(count) = trajectory(count).Coe.a; 
                   e(count) = trajectory(count).Coe.e;
                   i(count) = rad2deg(trajectory(count).Coe.i);
                   o(count) = rad2deg(trajectory(count).Coe.o);
                   w(count) = rad2deg(trajectory(count).Coe.w);
                   nu(count) = rad2deg(trajectory(count).Coe.nu);
                   
                   alpha(count) = rad2deg(trajectory(count).Alpha);
                   beta(count) = rad2deg(trajectory(count).Beta); 
                   mass(count) = trajectory(count).Mass;
                   Qvalue(count) = trajectory(count).Qvalue; 
                   Thrust(count) = trajectory(count).Thrust;
                   deltaV(count) = trajectory(count).DeltaV/1000; 
                   time(count) = trajectory(count).Ti; 
                   pos_t(count,:) = trajectory(count).pos; 
                   
                   % Assign color to the plot depending on the arc 
                   if trajectory(count).Thrust == 0 && trajectory(count).InShadow == 0 
                       color(count) = "b";
                       %pos_coast(coast,:) = trajectory(count).pos;
                       times_coast(count) = time(count);
                       times_is(count) = 0.002;
                       times_tc(count) = 0.002;
                       %coast = coast + 1;
                   elseif trajectory(count).InShadow == 1 && trajectory(count).Thrust == 0 
                       color(count) = "r";
                       %pos_is(is,:) = trajectory(count).pos;
                       times_coast(count) = 0.002;
                       times_is(count) = time(count);
                       times_tc(count) = 0.002;
                       %is = is + 1;
                   else
                       color(count) = "c";
                       %pos_tc(tc,:) = trajectory(count).pos;
                       times_coast(count) = 0.002;
                       times_is(count) = 0.002;
                       times_tc(count) = time(count);
                       %tc = tc + 1;
                   end  
               end
               vec = linspace(time(1),time(end),1.5*size); 
               pos_t_smooth(:,1) = spline(time',pos_t(:,1),vec');
               pos_t_smooth(:,2) = spline(time',pos_t(:,2),vec');
               pos_t_smooth(:,3) = spline(time',pos_t(:,3),vec'); 
               
               % COE and Params 
               app.plot_a = plot(app.UIAxes_a,time/3600/24,a,'k'); 
               app.plot_e = plot(app.UIAxes_e,time/3600/24,e,'k'); 
               app.plot_i = plot(app.UIAxes_i,time/3600/24,i,'k'); 
               app.plot_o = plot(app.UIAxes_o,time/3600/24,o,'k'); 
               app.plot_w = plot(app.UIAxes_w,time/3600/24,w,'k'); 
               app.plot_nu = plot(app.UIAxes_nu,time/3600/24,nu,'k'); 
               ylabel(app.UIAxes_nu,'$\Theta$','interpreter','latex');
               
               app.plot_alpha = plot(app.UIAxes_alpha,time/3600/24,alpha,'k'); 
               ylabel(app.UIAxes_alpha,'$\alpha [deg]$','interpreter','latex');
               app.plot_beta = plot(app.UIAxes_beta,time/3600/24,beta,'k'); 
               ylabel(app.UIAxes_beta,'$\beta [deg]$','interpreter','latex');
               app.plot_mass = plot(app.UIAxes_mass,time/3600/24,mass,'k'); 
               app.plot_dv = plot(app.UIAxes_dv,time/3600/24,deltaV,'k');
               ylabel(app.UIAxes_dv,'$\Delta V$ $\frac{km}{s}$','interpreter','latex');
               app.plot_q = plot(app.UIAxes_q,time/3600/24,Qvalue,'k'); 
               app.plot_thrust = stairs(app.UIAxes_thrust,time/3600/24,Thrust,'k');
               
               % Plot of the orbit
               
               % Initial and final orbits 
               TA = linspace(0,2*pi,64); 
               for idx = 1:length(TA) 
                 
                   orbit_0(idx).a = app.aEditField.Value*10^3;
                   orbit_0(idx).e = app.eEditField.Value;
                   orbit_0(idx).i = deg2rad(app.iEditField.Value);
                   orbit_0(idx).o = deg2rad(app.oEditField.Value);
                   orbit_0(idx).w = deg2rad(app.wEditField.Value);
                   orbit_0(idx).nu = TA(idx);
                   pos_0(idx,:) = COE_getPosition(orbit_0(idx)); 


                   orbit_f(idx).a = app.aEditField_2.Value*10^3; 
                   orbit_f(idx).e = app.eEditField_2.Value;
                   orbit_f(idx).i = deg2rad(app.iEditField_2.Value);
                   orbit_f(idx).o = deg2rad(app.oEditField_2.Value);
                   orbit_f(idx).w = deg2rad(app.wEditField_2.Value);
                   orbit_f(idx).nu = TA(idx);
                   pos_f(idx,:) = COE_getPosition(orbit_f(idx));
               end
               app.pos_00 = pos_0; 
               app.pos_ff = pos_f;
               
               image_earth = 'http://upload.wikimedia.org/wikipedia/commons/thumb/c/cd/Land_ocean_ice_2048.jpg/1024px-Land_ocean_ice_2048.jpg';
               cdata = imread(image_earth);
               alpha = 1;

               %dim = size(pos_f,2);
               [app.X,app.Y,app.Z] = ellipsoid(app.UIAxes_orbit,0,0,0,6378,6378,6378);
               app.h = surfl(app.UIAxes_orbit,app.X, app.Y, -app.Z);
               hold(app.UIAxes_orbit,'on')
               set (app.h, 'FaceColor', [0.7 0.7 0.7]);
               set(app.h, 'FaceColor', 'texturemap', 'CData', cdata, 'FaceAlpha', alpha, 'EdgeColor', 'none');
               axis(app.UIAxes_orbit,'equal')               
               hold(app.UIAxes_orbit,'on')
               
               % Plot initial orbit
               app.plot_orbit = plot3(app.UIAxes_orbit,pos_0(:,1)/1000,pos_0(:,2)/1000,pos_0(:,3)/1000,'y','LineWidth',2);
               hold(app.UIAxes_orbit,'on')
               
               % Plot Final orbit
               app.plot_orbit_2 = plot3(app.UIAxes_orbit,pos_f(:,1)/1000,pos_f(:,2)/1000,pos_f(:,3)/1000,'y','LineWidth',2);

               hold(app.UIAxes_orbit,'on') 
               
%                for p = 1:size 
%                  app.plot_orbit_t(p) = plot3(app.UIAxes_orbit,pos_t_smooth(p,1)/1000,pos_t_smooth(p,2)/1000,pos_t_smooth(p,3)/1000,'Color',color(p));
%                  hold(app.UIAxes_orbit,'on') 
%                end
               app.plot_orbit_t = plot3(app.UIAxes_orbit,pos_t_smooth(:,1)/1000,pos_t_smooth(:,2)/1000,pos_t_smooth(:,3)/1000,'Color','w');

%                app.plot_orbit_t_thrust = plot3(app.UIAxes_orbit,pos_tc_smooth(:,1)/1000,pos_tc_smooth(:,2)/1000,pos_tc_smooth(:,3)/1000,'c');
%                hold(app.UIAxes_orbit,'on') 
%                app.plot_orbit_t_coast = plot3(app.UIAxes_orbit,pos_coast_smooth(:,1)/1000,pos_coast_smooth(:,2)/1000,pos_coast_smooth(:,3)/1000,'b');
%                hold(app.UIAxes_orbit,'on') 
%                app.plot_orbit_t_eclipse = plot3(app.UIAxes_orbit,pos_is_smooth(:,1)/1000,pos_is_smooth(:,2)/1000,pos_is_smooth(:,3)/1000,'r');
%                

               % Showing cost values 
               app.DeltaVEditField.Value = num2str(cost(str2num(app.PopulationListBox.Value),2)); 
               app.TransferTimeEditField.Value = num2str(cost(str2num(app.PopulationListBox.Value),1));
               
        end

        % Value changed function: InitialOrbitCheckBox
        function InitialOrbitCheckBoxValueChanged(app, event)
            value = app.InitialOrbitCheckBox.Value;
            if value == 1 
                % Plot initial orbit 
               app.plot_orbit.Visible = 'on';
            else 
                app.plot_orbit.Visible = 'off';
%                 app.plot_orbit = plot3(app.UIAxes_orbit,app.pos_00(:,1)/1000,app.pos_00(:,2)/1000,app.pos_00(:,3)/1000,'k','LineWidth',2);
%                 hold(app.UIAxes_orbit,'on')
            end
        end

        % Value changed function: FinalOrbitCheckBox
        function FinalOrbitCheckBoxValueChanged(app, event)
            value = app.FinalOrbitCheckBox.Value;
            if value == 1 
                % Plot target orbit 
               app.plot_orbit_2.Visible = 'on';
            else 
                app.plot_orbit_2.Visible = 'off';
            end            
        end

        % Value changed function: TransferOrbitCheckBox
        function TransferOrbitCheckBoxValueChanged(app, event)
            value = app.TransferOrbitCheckBox.Value;
            if value == 1
                % Plot transfer orbit
            app.plot_orbit_t.Visible = 'on';
            else
            app.plot_orbit_t.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InitialOrbitPanel = uipanel(app.InputsTab);
            app.InitialOrbitPanel.Title = 'Initial Orbit';
            app.aEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.aEditField.ValueDisplayFormat = '%11.6g';
            app.aEditField.Value = 24500.36626;
            app.eEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.eEditField.Value = 0.7265761787;
            app.iEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.iEditField.Value = 5.953;
            app.oEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.oEditFieldLabel.Text = 'o';
            app.oEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.oEditField.Value = 99.0071;
            app.wEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.InitialOrbitPanel);
            app.thetaEditFieldLabel.Text = '\theta';
            app.thetaEditField = uieditfield(app.InitialOrbitPanel, 'numeric');
            app.SemimajoraxisLabel = uilabel(app.InitialOrbitPanel);
            app.SemimajoraxisLabel.Text = 'Semimajor axis';
            app.RAANLabel = uilabel(app.InitialOrbitPanel);
            app.RAANLabel.Text = 'RAAN';
            app.EccentricityLabel = uilabel(app.InitialOrbitPanel);
            app.EccentricityLabel.Text = 'Eccentricity';
            app.InclinationLabel = uilabel(app.InitialOrbitPanel);
            app.InclinationLabel.Text = 'Inclination';
            app.ArgumentofperiapsesLabel = uilabel(app.InitialOrbitPanel);
            app.ArgumentofperiapsesLabel.Text = 'Argument of periapses';
            app.TrueAnomalyLabel = uilabel(app.InitialOrbitPanel);
            app.TrueAnomalyLabel.Text = 'True Anomaly';
            app.FreeCheckBox = uicheckbox(app.InitialOrbitPanel);
            app.FreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBoxValueChanged, true);
            app.FreeCheckBox.Text = 'Free';
            app.FreeCheckBox_2 = uicheckbox(app.InitialOrbitPanel);
            app.FreeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_2ValueChanged, true);
            app.FreeCheckBox_2.Text = 'Free';
            app.FreeCheckBox_3 = uicheckbox(app.InitialOrbitPanel);
            app.FreeCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_3ValueChanged, true);
            app.FreeCheckBox_3.Text = 'Free';
            app.FreeCheckBox_4 = uicheckbox(app.InitialOrbitPanel);
            app.FreeCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_4ValueChanged, true);
            app.FreeCheckBox_4.Text = 'Free';
            app.kmLabel = uilabel(app.InitialOrbitPanel);
            app.kmLabel.Text = '[km]';
            app.Label_2 = uilabel(app.InitialOrbitPanel);
            app.Label_2.Text = '[-]';
            app.degLabel = uilabel(app.InitialOrbitPanel);
            app.degLabel.Text = '[deg]';
            app.degLabel_3 = uilabel(app.InitialOrbitPanel);
            app.degLabel_3.Text = '[deg]';
            app.TargetOrbitPanel = uipanel(app.InputsTab);
            app.TargetOrbitPanel.Title = 'Target Orbit';
            app.aEditField_2Label = uilabel(app.TargetOrbitPanel);
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.aEditField_2.Value = 42159.469;
            app.eEditField_2Label = uilabel(app.TargetOrbitPanel);
            app.eEditField_2Label.Text = 'e';
            app.eEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.eEditField_2.Value = 0.001;
            app.iEditField_2Label = uilabel(app.TargetOrbitPanel);
            app.iEditField_2Label.Text = 'i';
            app.iEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.iEditField_2.Value = 0.0573;
            app.oEditField_2Label = uilabel(app.TargetOrbitPanel);
            app.oEditField_2Label.Text = 'o';
            app.oEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.oEditField_2.Value = 145.5313;
            app.wEditField_2Label = uilabel(app.TargetOrbitPanel);
            app.wEditField_2Label.Text = 'w';
            app.wEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.wEditField_2.Value = 62.7389;
            app.thetaLabel = uilabel(app.TargetOrbitPanel);
            app.thetaLabel.Text = '$\theta$';
            app.thetaEditField_2 = uieditfield(app.TargetOrbitPanel, 'numeric');
            app.SemimajoraxisLabel_2 = uilabel(app.TargetOrbitPanel);
            app.SemimajoraxisLabel_2.Text = 'Semimajor axis';
            app.RAANLabel_2 = uilabel(app.TargetOrbitPanel);
            app.RAANLabel_2.Text = 'RAAN';
            app.EccentricityLabel_2 = uilabel(app.TargetOrbitPanel);
            app.EccentricityLabel_2.Text = 'Eccentricity';
            app.InclinationLabel_2 = uilabel(app.TargetOrbitPanel);
            app.InclinationLabel_2.Text = 'Inclination';
            app.ArgumentofperiapsesLabel_2 = uilabel(app.TargetOrbitPanel);
            app.ArgumentofperiapsesLabel_2.Text = 'Argument of periapses';
            app.TrueAnomalyLabel_2 = uilabel(app.TargetOrbitPanel);
            app.TrueAnomalyLabel_2.Text = 'True Anomaly';
            app.FreeCheckBox_5 = uicheckbox(app.TargetOrbitPanel);
            app.FreeCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_5ValueChanged, true);
            app.FreeCheckBox_5.Text = 'Free';
            app.FreeCheckBox_6 = uicheckbox(app.TargetOrbitPanel);
            app.FreeCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_6ValueChanged, true);
            app.FreeCheckBox_6.Text = 'Free';
            app.FreeCheckBox_7 = uicheckbox(app.TargetOrbitPanel);
            app.FreeCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_7ValueChanged, true);
            app.FreeCheckBox_7.Text = 'Free';
            app.FreeCheckBox_8 = uicheckbox(app.TargetOrbitPanel);
            app.FreeCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_8ValueChanged, true);
            app.FreeCheckBox_8.Text = 'Free';
            app.kmLabel_2 = uilabel(app.TargetOrbitPanel);
            app.kmLabel_2.Text = '[km]';
            app.Label_3 = uilabel(app.TargetOrbitPanel);
            app.Label_3.Text = '[-]';
            app.SingleTransferPropertiesPanel = uipanel(app.InputsTab);
            app.SingleTransferPropertiesPanel.Title = 'Single Transfer Properties';
            app.WeightParametersPanel = uipanel(app.SingleTransferPropertiesPanel);
            app.WeightParametersPanel.Title = 'Weight Parameters';
            app.W_aEditFieldLabel = uilabel(app.WeightParametersPanel);
            app.W_aEditFieldLabel.Text = 'W_a';
            app.W_aEditField = uieditfield(app.WeightParametersPanel, 'text');
            app.W_aEditField.Value = '29.72638';
            app.W_fEditFieldLabel = uilabel(app.WeightParametersPanel);
            app.W_fEditFieldLabel.Text = 'W_f';
            app.W_fEditField = uieditfield(app.WeightParametersPanel, 'text');
            app.W_fEditField.Value = '9.755235';
            app.W_gEditFieldLabel = uilabel(app.WeightParametersPanel);
            app.W_gEditFieldLabel.Text = 'W_g';
            app.W_gEditField = uieditfield(app.WeightParametersPanel, 'text');
            app.W_gEditField.Value = '28.968012';
            app.W_hEditFieldLabel = uilabel(app.WeightParametersPanel);
            app.W_hEditFieldLabel.Text = 'W_h';
            app.W_hEditField = uieditfield(app.WeightParametersPanel, 'text');
            app.W_hEditField.Value = '52.2943';
            app.W_kEditFieldLabel = uilabel(app.WeightParametersPanel);
            app.W_kEditFieldLabel.Text = 'W_k';
            app.W_kEditField = uieditfield(app.WeightParametersPanel, 'text');
            app.W_kEditField.Value = '13.40995';
            app.EffectivityParametersPanel = uipanel(app.SingleTransferPropertiesPanel);
            app.EffectivityParametersPanel.Title = 'Effectivity Parameters';
            app.etaAbsoluteEditFieldLabel = uilabel(app.EffectivityParametersPanel);
            app.etaAbsoluteEditFieldLabel.Text = 'eta Absolute';
            app.etaAbsoluteEditField = uieditfield(app.EffectivityParametersPanel, 'text');
            app.etaAbsoluteEditField.Value = '0';
            app.etaRelativeEditFieldLabel = uilabel(app.EffectivityParametersPanel);
            app.etaRelativeEditFieldLabel.Text = 'eta Relative';
            app.etaRelativeEditField = uieditfield(app.EffectivityParametersPanel, 'text');
            app.etaRelativeEditField.Value = '0';
            app.ScaleFunctionParametersPanel = uipanel(app.SingleTransferPropertiesPanel);
            app.ScaleFunctionParametersPanel.Title = 'Scale Function Parameters';
            app.mEditFieldLabel = uilabel(app.ScaleFunctionParametersPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.ScaleFunctionParametersPanel, 'text');
            app.mEditField.Value = '0.8838';
            app.nEditFieldLabel = uilabel(app.ScaleFunctionParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ScaleFunctionParametersPanel, 'text');
            app.nEditField.Value = '6.2920';
            app.rEditFieldLabel = uilabel(app.ScaleFunctionParametersPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.ScaleFunctionParametersPanel, 'text');
            app.rEditField.Value = '0.1077';
            app.ProblemtypeDropDownLabel = uilabel(app.InputsTab);
            app.ProblemtypeDropDownLabel.Text = 'Problem type';
            app.ProblemtypeDropDown = uidropdown(app.InputsTab);
            app.ProblemtypeDropDown.Items = {'Single Transfer', 'Optimization '};
            app.ProblemtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemtypeDropDownValueChanged, true);
            app.ProblemtypeDropDown.Value = 'Single Transfer';
            app.SpacecraftPropertiesPanel = uipanel(app.InputsTab);
            app.SpacecraftPropertiesPanel.Title = 'Spacecraft Properties';
            app.m_0EditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.m_0EditFieldLabel.Text = 'm_0';
            app.m_0EditField = uieditfield(app.SpacecraftPropertiesPanel, 'text');
            app.m_0EditField.Value = '450';
            app.T_maxEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.T_maxEditFieldLabel.Text = 'T_max';
            app.T_maxEditField = uieditfield(app.SpacecraftPropertiesPanel, 'text');
            app.T_maxEditField.Value = '0.22';
            app.IspEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.IspEditFieldLabel.Text = 'Isp';
            app.IspEditField = uieditfield(app.SpacecraftPropertiesPanel, 'text');
            app.IspEditField.Value = '1600';
            app.InitialMassLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.InitialMassLabel.Text = 'Initial Mass';
            app.MaximumThrustLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.MaximumThrustLabel.Text = 'Maximum Thrust';
            app.SpecificImpulseLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.SpecificImpulseLabel.Text = 'Specific Impulse';
            app.NLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.NLabel.Text = '[N]';
            app.PerturbationsConstraintsPanel = uipanel(app.InputsTab);
            app.PerturbationsConstraintsPanel.Title = 'Perturbations / Constraints';
            app.EclipseCheckBox = uicheckbox(app.PerturbationsConstraintsPanel);
            app.EclipseCheckBox.Text = 'Eclipse';
            app.MinimumRpCheckBox = uicheckbox(app.PerturbationsConstraintsPanel);
            app.MinimumRpCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumRpCheckBoxValueChanged, true);
            app.MinimumRpCheckBox.Text = 'Minimum Rp';
            app.EditField = uieditfield(app.PerturbationsConstraintsPanel, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField.Value = 6378;
            app.J2CheckBox = uicheckbox(app.PerturbationsConstraintsPanel);
            app.J2CheckBox.Text = 'J2';
            app.kmLabel_3 = uilabel(app.PerturbationsConstraintsPanel);
            app.kmLabel_3.Enable = 'off';
            app.kmLabel_3.Text = '[km]';
            app.SimulationOptionsPanel = uipanel(app.InputsTab);
            app.SimulationOptionsPanel.Title = 'Simulation Options';
            app.Label = uilabel(app.SimulationOptionsPanel);
            app.Label.Text = '';
            app.MaximumTransferTimeEditFieldLabel = uilabel(app.SimulationOptionsPanel);
            app.MaximumTransferTimeEditFieldLabel.Text = 'Maximum Transfer Time';
            app.MaximumTransferTimeEditField = uieditfield(app.SimulationOptionsPanel, 'numeric');
            app.MaximumTransferTimeEditField.Value = 1;
            app.FreeCheckBox_9 = uicheckbox(app.SimulationOptionsPanel);
            app.FreeCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @FreeCheckBox_9ValueChanged, true);
            app.FreeCheckBox_9.Text = 'Free';
            app.StartTransferDateDatePickerLabel = uilabel(app.SimulationOptionsPanel);
            app.StartTransferDateDatePickerLabel.Text = 'Start Transfer Date';
            app.StartTransferDateDatePicker = uidatepicker(app.SimulationOptionsPanel);
            app.StartTransferDateDatePicker.Value = datetime([2016 10 12]);
            app.DatePicker = uidatepicker(app.SimulationOptionsPanel);
            app.DatePicker.Enable = 'off';
            app.DatePicker.Value = datetime([2040 4 27]);
            app.OptimizatorOptionsPanel = uipanel(app.InputsTab);
            app.OptimizatorOptionsPanel.Title = 'Optimizator Options';
            app.MaxPopSizeEditFieldLabel = uilabel(app.OptimizatorOptionsPanel);
            app.MaxPopSizeEditFieldLabel.Text = 'Max Pop Size';
            app.MaxPopSizeEditField = uieditfield(app.OptimizatorOptionsPanel, 'numeric');
            app.MaxPopSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPopSizeEditFieldValueChanged, true);
            app.MaxPopSizeEditField.Value = 50;
            app.MaxGenerationEditFieldLabel = uilabel(app.OptimizatorOptionsPanel);
            app.MaxGenerationEditFieldLabel.Text = 'Max Generation';
            app.MaxGenerationEditField = uieditfield(app.OptimizatorOptionsPanel, 'numeric');
            app.MaxGenerationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxGenerationEditFieldValueChanged, true);
            app.MaxGenerationEditField.Value = 50;
            app.CrossoverFractionEditFieldLabel = uilabel(app.OptimizatorOptionsPanel);
            app.CrossoverFractionEditFieldLabel.Text = 'Crossover Fraction';
            app.CrossoverFractionEditField = uieditfield(app.OptimizatorOptionsPanel, 'numeric');
            app.CrossoverFractionEditField.Value = 0.8;
            app.MutationFractionEditFieldLabel = uilabel(app.OptimizatorOptionsPanel);
            app.MutationFractionEditFieldLabel.Text = 'Mutation Fraction';
            app.MutationFractionEditField = uieditfield(app.OptimizatorOptionsPanel, 'numeric');
            app.MutationFractionEditField.Value = 0.8;
            app.ParallelComputationCheckBox = uicheckbox(app.OptimizatorOptionsPanel);
            app.ParallelComputationCheckBox.Text = 'Parallel Computation';
            app.CentralBodyPropertiesPanel = uipanel(app.InputsTab);
            app.CentralBodyPropertiesPanel.Title = 'Central Body Properties';
            app.DropDown = uidropdown(app.CentralBodyPropertiesPanel);
            app.DropDown.Items = {'Earth', 'Moon', 'Mars'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Earth';
            app.GravParamEditFieldLabel = uilabel(app.CentralBodyPropertiesPanel);
            app.GravParamEditFieldLabel.Enable = 'off';
            app.GravParamEditFieldLabel.Text = 'Grav.Param';
            app.GravParamEditField = uieditfield(app.CentralBodyPropertiesPanel, 'numeric');
            app.GravParamEditField.Enable = 'off';
            app.GravParamEditField.Value = 398600440000000;
            app.J2EditFieldLabel = uilabel(app.CentralBodyPropertiesPanel);
            app.J2EditFieldLabel.Enable = 'off';
            app.J2EditFieldLabel.Text = 'J2';
            app.J2EditField = uieditfield(app.CentralBodyPropertiesPanel, 'numeric');
            app.J2EditField.Enable = 'off';
            app.J2EditField.Value = 0.001082626;
            app.BodyRadiusEditFieldLabel = uilabel(app.CentralBodyPropertiesPanel);
            app.BodyRadiusEditFieldLabel.Enable = 'off';
            app.BodyRadiusEditFieldLabel.Text = 'Body Radius';
            app.BodyRadiusEditField = uieditfield(app.CentralBodyPropertiesPanel, 'numeric');
            app.BodyRadiusEditField.Enable = 'off';
            app.BodyRadiusEditField.Value = 6378140;
            app.RunButton = uibutton(app.InputsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.degLabel_2 = uilabel(app.InputsTab);
            app.degLabel_2.Text = '[deg]';
            app.degLabel_4 = uilabel(app.InputsTab);
            app.degLabel_4.Text = '[deg]';
            app.degLabel_5 = uilabel(app.InputsTab);
            app.degLabel_5.Text = '[deg]';
            app.degLabel_6 = uilabel(app.InputsTab);
            app.degLabel_6.Text = '[deg]';
            app.degLabel_7 = uilabel(app.InputsTab);
            app.degLabel_7.Text = '[deg]';
            app.degLabel_8 = uilabel(app.InputsTab);
            app.degLabel_8.Text = '[deg]';
            app.kgLabel = uilabel(app.InputsTab);
            app.kgLabel.Text = '[kg]';
            app.sLabel = uilabel(app.InputsTab);
            app.sLabel.Text = '[s]';
            app.yearsLabel = uilabel(app.InputsTab);
            app.yearsLabel.Text = '[years]';
            app.OutputPanel = uipanel(app.InputsTab);
            app.OutputPanel.Title = 'Output';
            app.TextArea = uitextarea(app.OutputPanel);
            app.OptimizatorTab = uitab(app.TabGroup);
            app.OptimizatorTab.Title = 'Optimizator';
            app.UIAxes_pareto = uiaxes(app.OptimizatorTab);
            title(app.UIAxes_pareto, 'Title')
            xlabel(app.UIAxes_pareto, 'Time of Flight [days]')
            ylabel(app.UIAxes_pareto, 'Delta V [km/s]')
            app.GenerationListBoxLabel = uilabel(app.OptimizatorTab);
            app.GenerationListBoxLabel.Enable = 'off';
            app.GenerationListBoxLabel.Text = 'Generation';
            app.GenerationListBox = uilistbox(app.OptimizatorTab);
            app.GenerationListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.GenerationListBox.ValueChangedFcn = createCallbackFcn(app, @GenerationPopulationListBoxValueChanged, true);
            app.GenerationListBox.Enable = 'off';
            app.GenerationListBox.Value = '50';
            app.PopulationListBoxLabel = uilabel(app.OptimizatorTab);
            app.PopulationListBoxLabel.Enable = 'off';
            app.PopulationListBoxLabel.Text = 'Population';
            app.PopulationListBox = uilistbox(app.OptimizatorTab);
            app.PopulationListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.PopulationListBox.ValueChangedFcn = createCallbackFcn(app, @GenerationPopulationListBoxValueChanged, true);
            app.PopulationListBox.Enable = 'off';
            app.PopulationListBox.Value = '1';
            app.TransferOrbitCostPanel_2 = uipanel(app.OptimizatorTab);
            app.TransferOrbitCostPanel_2.Title = 'Transfer Orbit Cost';
            app.kmsLabel_2 = uilabel(app.TransferOrbitCostPanel_2);
            app.kmsLabel_2.Text = '[km/s]';
            app.daysLabel_2 = uilabel(app.TransferOrbitCostPanel_2);
            app.daysLabel_2.Text = '[days]';
            app.DeltaVEditField_2Label = uilabel(app.TransferOrbitCostPanel_2);
            app.DeltaVEditField_2Label.Text = 'Delta V';
            app.DeltaVEditField_2 = uieditfield(app.TransferOrbitCostPanel_2, 'text');
            app.DeltaVEditField_2.Editable = 'off';
            app.TransferTimeEditField_2Label = uilabel(app.TransferOrbitCostPanel_2);
            app.TransferTimeEditField_2Label.Text = 'Transfer Time';
            app.TransferTimeEditField_2 = uieditfield(app.TransferOrbitCostPanel_2, 'text');
            app.TransferTimeEditField_2.Editable = 'off';
            app.PlottransferButton = uibutton(app.OptimizatorTab, 'push');
            app.PlottransferButton.ButtonPushedFcn = createCallbackFcn(app, @PlottransferButtonPushed, true);
            app.PlottransferButton.Text = 'Plot transfer';
            app.COETab = uitab(app.TabGroup);
            app.COETab.Title = 'COE';
            app.UIAxes_a = uiaxes(app.COETab);
            title(app.UIAxes_a, '')
            xlabel(app.UIAxes_a, 'Time [days]')
            ylabel(app.UIAxes_a, 'Semimajor axis a [km]')
            app.UIAxes_e = uiaxes(app.COETab);
            title(app.UIAxes_e, '')
            xlabel(app.UIAxes_e, 'Time [days]')
            ylabel(app.UIAxes_e, 'Eccentricity [-]')
            app.UIAxes_i = uiaxes(app.COETab);
            title(app.UIAxes_i, '')
            xlabel(app.UIAxes_i, 'Time [days]')
            ylabel(app.UIAxes_i, 'Inclination [deg]')
            app.UIAxes_o = uiaxes(app.COETab);
            title(app.UIAxes_o, '')
            xlabel(app.UIAxes_o, 'Time [days]')
            ylabel(app.UIAxes_o, 'RAAN [deg]')
            app.UIAxes_w = uiaxes(app.COETab);
            title(app.UIAxes_w, '')
            xlabel(app.UIAxes_w, 'Time [days]')
            ylabel(app.UIAxes_w, 'Argument of Periapses [deg]')
            app.UIAxes_nu = uiaxes(app.COETab);
            title(app.UIAxes_nu, '')
            xlabel(app.UIAxes_nu, 'Time [days]')
            ylabel(app.UIAxes_nu, 'True Anomaly [deg]')
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.UIAxes_alpha = uiaxes(app.ParamsTab);
            title(app.UIAxes_alpha, '')
            xlabel(app.UIAxes_alpha, 'Time [days]')
            ylabel(app.UIAxes_alpha, 'Alpha [deg]')
            app.UIAxes_beta = uiaxes(app.ParamsTab);
            title(app.UIAxes_beta, '')
            xlabel(app.UIAxes_beta, 'Time [days]')
            ylabel(app.UIAxes_beta, 'Beta [deg]')
            app.UIAxes_thrust = uiaxes(app.ParamsTab);
            title(app.UIAxes_thrust, '')
            xlabel(app.UIAxes_thrust, 'Time [days]')
            ylabel(app.UIAxes_thrust, 'Thrust [N]')
            app.UIAxes_q = uiaxes(app.ParamsTab);
            title(app.UIAxes_q, '')
            xlabel(app.UIAxes_q, 'Time [days]')
            ylabel(app.UIAxes_q, 'Q [-]')
            app.UIAxes_dv = uiaxes(app.ParamsTab);
            title(app.UIAxes_dv, '')
            xlabel(app.UIAxes_dv, 'Time [days]')
            ylabel(app.UIAxes_dv, 'Delta V [km/s]')
            app.UIAxes_mass = uiaxes(app.ParamsTab);
            title(app.UIAxes_mass, '')
            xlabel(app.UIAxes_mass, 'Time [days]')
            ylabel(app.UIAxes_mass, 'Mass [kg]')
            app.OrbitTab = uitab(app.TabGroup);
            app.OrbitTab.Title = 'Orbit';
            app.InitialOrbitCheckBox = uicheckbox(app.OrbitTab);
            app.InitialOrbitCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitialOrbitCheckBoxValueChanged, true);
            app.InitialOrbitCheckBox.Text = 'Initial Orbit';
            app.InitialOrbitCheckBox.Value = true;
            app.FinalOrbitCheckBox = uicheckbox(app.OrbitTab);
            app.FinalOrbitCheckBox.ValueChangedFcn = createCallbackFcn(app, @FinalOrbitCheckBoxValueChanged, true);
            app.FinalOrbitCheckBox.Text = 'Final Orbit';
            app.FinalOrbitCheckBox.Value = true;
            app.TransferOrbitCheckBox = uicheckbox(app.OrbitTab);
            app.TransferOrbitCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransferOrbitCheckBoxValueChanged, true);
            app.TransferOrbitCheckBox.Text = 'Transfer Orbit';
            app.TransferOrbitCheckBox.Value = true;
            app.UIAxes_orbit = uiaxes(app.OrbitTab);
            title(app.UIAxes_orbit, '')
            xlabel(app.UIAxes_orbit, 'X')
            ylabel(app.UIAxes_orbit, 'Y')
            app.TransferOrbitCostPanel = uipanel(app.OrbitTab);
            app.TransferOrbitCostPanel.Title = 'Transfer Orbit Cost';
            app.kmsLabel = uilabel(app.TransferOrbitCostPanel);
            app.kmsLabel.Text = '[km/s]';
            app.daysLabel = uilabel(app.TransferOrbitCostPanel);
            app.daysLabel.Text = '[days]';
            app.DeltaVEditFieldLabel = uilabel(app.TransferOrbitCostPanel);
            app.DeltaVEditFieldLabel.Text = 'Delta V';
            app.DeltaVEditField = uieditfield(app.TransferOrbitCostPanel, 'text');
            app.DeltaVEditField.Editable = 'off';
            app.TransferTimeEditFieldLabel = uilabel(app.TransferOrbitCostPanel);
            app.TransferTimeEditFieldLabel.Text = 'Transfer Time';
            app.TransferTimeEditField = uieditfield(app.TransferOrbitCostPanel, 'text');
            app.TransferTimeEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_MOLTO_bien

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end